classdef project_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FinalStateLabel       matlab.ui.control.Label
        Button                matlab.ui.control.Button
        FrontGainSlider       matlab.ui.control.Slider
        FrontGainSliderLabel  matlab.ui.control.Label
        SimulationControls    simulink.ui.control.SimulationControls
        SimulationProgress    simulink.ui.control.SimulationProgress
        TimeScope             matlab.ui.scope.TimeScope
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SimulationControls
        function SimulationControlsSizeChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gainValue = app.FrontGainSlider.Value;
            set_param('Robot_Project/Robot_sensor/Front_Gain_Calibration', 'Gain', num2str(gainValue));
            simOut = sim('Robot_Project');
            logged = simOut.get('Final_State_Code_Log');
            finalState = logged.Data(end);
            app.FinalStateLabel.Text = ['Final State: ' num2str(finalState)];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Robot_sensor/From9:1'};
            app.TimeScope.Title = 'Front_Dist_cm';
            app.TimeScope.YLabel = 'Amplitude';
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationControls.SizeChangedFcn = createCallbackFcn(app, @SimulationControlsSizeChanged, true);
            app.FrontGainSliderLabel = uilabel(app.UIFigure);
            app.FrontGainSliderLabel.Text = 'FrontGainSlider';
            app.FrontGainSlider = uislider(app.UIFigure);
            app.FrontGainSlider.Limits = [0 5];
            app.FrontGainSlider.Value = 0.5;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.FinalStateLabel = uilabel(app.UIFigure);
            app.FinalStateLabel.Text = 'Final State: --';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Robot_sensor/From9:1'], app.TimeScope);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_test

            % Associate the Simulink Model
            app.Simulation = simulation('Robot_Project');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end